classdef RFScanner_1213 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        GridLayout                     matlab.ui.container.GridLayout
        LeftPanel                      matlab.ui.container.Panel
        RunButton                      matlab.ui.control.StateButton
        FileNameEditFieldLabel         matlab.ui.control.Label
        FileNameEditField              matlab.ui.control.EditField
        FrameStartIdxEditFieldLabel    matlab.ui.control.Label
        FrameStartIdxEditField         matlab.ui.control.NumericEditField
        DetTHEditFieldLabel            matlab.ui.control.Label
        DetTHEditField                 matlab.ui.control.NumericEditField
        FileNameEditField_2            matlab.ui.control.EditField
        FileSelectButton               matlab.ui.control.StateButton
        FrameEndIdxEditFieldLabel      matlab.ui.control.Label
        FrameEndIdxEditField           matlab.ui.control.NumericEditField
        AveragedFrameResultCheckBox    matlab.ui.control.CheckBox
        IndividualFrameResultCheckBox  matlab.ui.control.CheckBox
        RightPanel                     matlab.ui.container.Panel
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = public)
        FileSelection=0;
        FileName=[];
        PathName=[];
        GlobalTest=100;
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: RunButton
        function RunButtonValueChanged(app, event)
            value = app.RunButton.Value;
            
            % Variable Initialization
            FrameStart=app.FrameStartIdxEditField.Value;
            FrameEnd=app.FrameEndIdxEditField.Value;
            DetTH=app.DetTHEditField.Value;
            Indi_ResultOn=app.IndividualFrameResultCheckBox.Value;
            Avg_ResultOn=app.AveragedFrameResultCheckBox.Value;
            
            
            if app.FileSelection==0
                
                errordlg('File Not Selected');
                
            else
                close all;
                load([app.PathName app.FileName]);
                for FrameIdx=FrameStart:FrameEnd
                    SelAnt1 = (burstCaptures(:,1,FrameIdx));
                    SelAnt2 = (burstCaptures(:,2,FrameIdx));
                    FreqAnt1=fft(SelAnt1);
                    
                    n_ind = 1:length(SelAnt1);
                    for k = 1:length(SelAnt1)
                        ex = exp(-j*2*pi*(k-1)*(n_ind-1)/length(SelAnt1));
                        FreqAnt1(k) = ex*SelAnt1;
                    end
                    
                    FreqAnt2=fft(SelAnt2);
                    n_ind = 1:length(SelAnt2);
                    for k = 1:length(SelAnt2)
                        ex = exp(-j*2*pi*(k-1)*(n_ind-1)/length(SelAnt2));
                        FreqAnt2(k) = ex*SelAnt2;
                    end


                    ConjMult_Ant2Ant1=FreqAnt1.*conj(FreqAnt2);
                    [Pos1 Pos2]=find(abs(ConjMult_Ant2Ant1)>DetTH);
                    SelBigVal=ConjMult_Ant2Ant1(Pos1);
                    SelBigVal_Degree=angle(SelBigVal)/pi*180;
                    MeanAngle=mean(SelBigVal_Degree);
                    
                    if Indi_ResultOn
                        figure(FrameIdx);
                        subplot(2,2,1);
                        plot(abs(FreqAnt1)); legend('Freq of Ant1');
                        
                        subplot(2,2,2);
                        plot(abs(FreqAnt2), 'r'); legend('Freq of Ant2');
                        
                        subplot(2,2,3);
                        plot(angle(ConjMult_Ant2Ant1)/pi*180); legend('Phase Difference of All in Freq');
                        
                        subplot(2,2,4);
                        plot(real(SelAnt1)); hold on; plot(real(SelAnt2), 'r'); legend('Time of Real Ant1','Time of Real Ant2'); hold off;
                        
                    end
                    
                    if Avg_ResultOn
                        figure(200); title('Averaged Angle of Frame')
                        hold on; plot(FrameIdx, MeanAngle, 'ro'); axis([0 FrameEnd+1 -180 180]);
                    end
                    
                    
                end
                
            end


        end

        % Value changed function: FileSelectButton
        function FileSelectButtonValueChanged(app, event)
            value = app.FileSelectButton.Value;
            [filename, pathname] = uigetfile('*.mat', 'Pick a TX Calibration MAT File');
            app.FileName=filename;
            app.PathName=pathname;
            app.FileSelection=1;
            app.FileNameEditField.Value=filename;
            app.FileNameEditField_2.Value=pathname;
            
            
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {464, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.RunButton = uibutton(app.LeftPanel, 'state');
            app.RunButton.ValueChangedFcn = createCallbackFcn(app, @RunButtonValueChanged, true);
            app.RunButton.Text = 'Run';
            app.FileNameEditFieldLabel = uilabel(app.LeftPanel);
            app.FileNameEditFieldLabel.Text = 'File Name';
            app.FileNameEditField = uieditfield(app.LeftPanel, 'text');
            app.FileNameEditField.Editable = 'off';
            app.FileNameEditField.Value = 'No File Selected';
            app.FrameStartIdxEditFieldLabel = uilabel(app.LeftPanel);
            app.FrameStartIdxEditFieldLabel.Text = 'Frame Start Idx';
            app.FrameStartIdxEditField = uieditfield(app.LeftPanel, 'numeric');
            app.FrameStartIdxEditField.Value = 1;
            app.DetTHEditFieldLabel = uilabel(app.LeftPanel);
            app.DetTHEditFieldLabel.Text = 'Det TH';
            app.DetTHEditField = uieditfield(app.LeftPanel, 'numeric');
            app.DetTHEditField.Value = 100000000000;
            app.FileNameEditField_2 = uieditfield(app.LeftPanel, 'text');
            app.FileNameEditField_2.Editable = 'off';
            app.FileNameEditField_2.Value = 'No Folder Select';
            app.FileSelectButton = uibutton(app.LeftPanel, 'state');
            app.FileSelectButton.ValueChangedFcn = createCallbackFcn(app, @FileSelectButtonValueChanged, true);
            app.FileSelectButton.Text = 'File Select';
            app.FrameEndIdxEditFieldLabel = uilabel(app.LeftPanel);
            app.FrameEndIdxEditFieldLabel.Text = 'Frame End Idx';
            app.FrameEndIdxEditField = uieditfield(app.LeftPanel, 'numeric');
            app.FrameEndIdxEditField.Value = 2;
            app.AveragedFrameResultCheckBox = uicheckbox(app.LeftPanel);
            app.AveragedFrameResultCheckBox.Text = 'Averaged Frame Result';
            app.AveragedFrameResultCheckBox.Value = true;
            app.IndividualFrameResultCheckBox = uicheckbox(app.LeftPanel);
            app.IndividualFrameResultCheckBox.Text = 'Individual Frame Result';
            app.IndividualFrameResultCheckBox.Value = true;
            app.RightPanel = uipanel(app.GridLayout);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = RFScanner_1213

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end